classdef EffectsOptions < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                   matlab.ui.Figure
        ReverbPredelayTimeSlider   matlab.ui.control.Slider
        PredelayTimeSlider_2Label  matlab.ui.control.Label
        ApplyButton                matlab.ui.control.Button
        DelayLabel                 matlab.ui.control.Label
        ReverbLabel                matlab.ui.control.Label
        DelayTimeSlider            matlab.ui.control.Slider
        TimeSliderLabel            matlab.ui.control.Label
        DelayFeedbackSlider        matlab.ui.control.Slider
        FeedbackSliderLabel        matlab.ui.control.Label
        DelayDryWetSlider          matlab.ui.control.Slider
        DryWetSliderLabel          matlab.ui.control.Label
        ReverbTimeSlider           matlab.ui.control.Slider
        TimeSlider_2Label          matlab.ui.control.Label
        ReverbFeedbackSlider       matlab.ui.control.Slider
        FeedbackSlider_2Label      matlab.ui.control.Label
        ReverbDryWetSlider         matlab.ui.control.Slider
        DryWetSlider_2Label        matlab.ui.control.Label
        BackButton                 matlab.ui.control.Button
    end

    
    properties (Access = private)
        callingApp  % Description
        delay
        reverb
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app, mainapp, delay, reverb)
            app.callingApp = mainapp;
            app.delay = delay;
            app.reverb = reverb;
        end

        % Button pushed function: BackButton
        function BackButtonPushed(app, event)
            app.callingApp.updateEffectsTrack1(app.delay, app.reverb);
            delete(app)
        end

        % Close request function: UIFigure
        function UIFigureCloseRequest(app, event)
            delete(app)
        end

        % Value changed function: DelayTimeSlider
        function DelayTimeSliderValueChanged(app, event)
            value = app.DelayTimeSlider.Value;
            app.delay.combDelay = 1000.*value;
        end

        % Value changed function: DelayFeedbackSlider
        function DelayFeedbackSliderValueChanged(app, event)
            value = app.DelayFeedbackSlider.Value;
            app.delay.feedback = value;
        end

        % Value changed function: DelayDryWetSlider
        function DelayDryWetSliderValueChanged(app, event)
            value = app.DelayDryWetSlider.Value;
            app.delay.wetGain = 0.5+value;
            app.delay.dryGain = 0.5+(1-value);
        end

        % Button pushed function: ApplyButton
        function ApplyButtonPushed(app, event)
            app.callingApp.updateEffectsTrack1(app.delay, app.reverb);
        end

        % Value changed function: ReverbPredelayTimeSlider
        function ReverbPredelayTimeSliderValueChanged(app, event)
            value = app.ReverbPredelayTimeSlider.Value;
            
        end

        % Value changed function: ReverbTimeSlider
        function ReverbTimeSliderValueChanged(app, event)
            value = app.ReverbTimeSlider.Value;
            
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.UIFigure.CloseRequestFcn = createCallbackFcn(app, @UIFigureCloseRequest, true);
            app.BackButton = uibutton(app.UIFigure, 'push');
            app.BackButton.ButtonPushedFcn = createCallbackFcn(app, @BackButtonPushed, true);
            app.BackButton.Text = 'Back';
            app.DryWetSlider_2Label = uilabel(app.UIFigure);
            app.DryWetSlider_2Label.Text = 'Dry/Wet';
            app.ReverbDryWetSlider = uislider(app.UIFigure);
            app.FeedbackSlider_2Label = uilabel(app.UIFigure);
            app.FeedbackSlider_2Label.Text = 'Feedback';
            app.ReverbFeedbackSlider = uislider(app.UIFigure);
            app.TimeSlider_2Label = uilabel(app.UIFigure);
            app.TimeSlider_2Label.Text = 'Time';
            app.ReverbTimeSlider = uislider(app.UIFigure);
            app.ReverbTimeSlider.Limits = [0 10];
            app.ReverbTimeSlider.MajorTicks = [0 5 10];
            app.ReverbTimeSlider.ValueChangedFcn = createCallbackFcn(app, @ReverbTimeSliderValueChanged, true);
            app.DryWetSliderLabel = uilabel(app.UIFigure);
            app.DryWetSliderLabel.Text = 'Dry/Wet';
            app.DelayDryWetSlider = uislider(app.UIFigure);
            app.DelayDryWetSlider.Limits = [0 1];
            app.DelayDryWetSlider.MajorTicks = [0 0.5 1];
            app.DelayDryWetSlider.ValueChangedFcn = createCallbackFcn(app, @DelayDryWetSliderValueChanged, true);
            app.DelayDryWetSlider.Value = 0.5;
            app.FeedbackSliderLabel = uilabel(app.UIFigure);
            app.FeedbackSliderLabel.Text = 'Feedback';
            app.DelayFeedbackSlider = uislider(app.UIFigure);
            app.DelayFeedbackSlider.Limits = [0 12];
            app.DelayFeedbackSlider.MajorTicks = [0 6 12];
            app.DelayFeedbackSlider.ValueChangedFcn = createCallbackFcn(app, @DelayFeedbackSliderValueChanged, true);
            app.DelayFeedbackSlider.MinorTicks = [];
            app.DelayFeedbackSlider.Value = 6;
            app.TimeSliderLabel = uilabel(app.UIFigure);
            app.TimeSliderLabel.Text = 'Time';
            app.DelayTimeSlider = uislider(app.UIFigure);
            app.DelayTimeSlider.Limits = [0 10];
            app.DelayTimeSlider.ValueChangedFcn = createCallbackFcn(app, @DelayTimeSliderValueChanged, true);
            app.DelayTimeSlider.Value = 5;
            app.ReverbLabel = uilabel(app.UIFigure);
            app.ReverbLabel.Text = 'Reverb';
            app.DelayLabel = uilabel(app.UIFigure);
            app.DelayLabel.Text = 'Delay';
            app.ApplyButton = uibutton(app.UIFigure, 'push');
            app.ApplyButton.ButtonPushedFcn = createCallbackFcn(app, @ApplyButtonPushed, true);
            app.ApplyButton.Text = 'Apply';
            app.PredelayTimeSlider_2Label = uilabel(app.UIFigure);
            app.PredelayTimeSlider_2Label.Text = 'Pre-delay Time';
            app.ReverbPredelayTimeSlider = uislider(app.UIFigure);
            app.ReverbPredelayTimeSlider.Limits = [0 10];
            app.ReverbPredelayTimeSlider.MajorTicks = [0 5 10];
            app.ReverbPredelayTimeSlider.ValueChangedFcn = createCallbackFcn(app, @ReverbPredelayTimeSliderValueChanged, true);
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = EffectsOptions(varargin)

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @(app)startupFcn(app, varargin{:}))

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end